classdef GUI_CurateSliceNeuroReg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SliceDropDownLabel          matlab.ui.control.Label
        SliceDropDown               matlab.ui.control.DropDown
        ShowAllgenesCheckBox        matlab.ui.control.CheckBox
        LoadthisSessionSliceButton  matlab.ui.control.Button
        Ch1MinEditField_2           matlab.ui.control.NumericEditField
        Ch2MinEditField_2           matlab.ui.control.NumericEditField
        MinLabel_2                  matlab.ui.control.Label
        MaxLabel_2                  matlab.ui.control.Label
        GadLabel                    matlab.ui.control.Label
        GcampLabel                  matlab.ui.control.Label
        Slider_Ch1                  matlab.ui.control.Slider
        Slider_Ch2                  matlab.ui.control.Slider
        Ch2MinEditField_3           matlab.ui.control.NumericEditField
        DAPILabel                   matlab.ui.control.Label
        Slider_Ch3                  matlab.ui.control.Slider
        CheckBox_2                  matlab.ui.control.CheckBox
        CheckBox_3                  matlab.ui.control.CheckBox
        SaveResultsButton           matlab.ui.control.Button
        MinLabel_3                  matlab.ui.control.Label
        MaxLabel_3                  matlab.ui.control.Label
        MinLabel_4                  matlab.ui.control.Label
        MaxLabel_4                  matlab.ui.control.Label
        Rotate90Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        SliceList % Description
        SliceID
        SliceNB
        folderName
        o
        ROI_ID
        Init
        GenesToPlot
        currentROI
        Replot
        SliceROI
        Range
        hROI
        ISSplot
        ChangeQual
        Axes1
        mainFig
        ToolMode
        BigImages
        S
        
    end
    
    methods (Access = public)
        
        %% plot ISS results
        function app = plot2(app)
            
            ImRed = imadjust(app.BigImages.GadImg,[app.Ch1MinEditField_2.Value app.Slider_Ch1.Value]);
            ImGreen = ImRed*0;
            ImBlue = ImRed*0;
            if ~isempty(app.BigImages.GcampImg) && app.CheckBox_2.Value == 1
                ImGreen = imadjust(app.BigImages.GcampImg,[app.Ch2MinEditField_2.Value app.Slider_Ch2.Value])  ;
            end
            if ~isempty(app.BigImages.DapiImg) && app.CheckBox_3.Value == 1
                ImBlue = imadjust(app.BigImages.DapiImg,[app.Ch2MinEditField_3.Value app.Slider_Ch3.Value]);
            end
            C = cat(3,ImRed,ImGreen,ImBlue);
            if  app.Replot  == 0 
            app.mainFig = figure(234321);
            app.S = imagesc(C);
             app.Axes1 = gca;
            addToolbarExplorationButtons(app.mainFig) % Add the axes controls back to the figure toolbar
            app.Axes1.Toolbar.Visible = 'off'; % Hide the integrated axes toolbar
%                iss_show_genes(app.GenesToPlot,app.Axes1);
               
            app.ToolMode = 'Exploration.Pan';
            figkeys.setHotkeys_stack2(app.mainFig,app);
            set(app.mainFig,'units','normalized','outerposition',[0.2 0.1 0.8 0.90])
            daspect([1 1 1])
            camroll(app.Axes1,90)
             else
                set(app.S,'CData',C);
            end
            if app.Init == 0
%                 camroll(app.Axes1,90)
                set(gca,'Position',[0.05,0.05,0.95,0.95]);
                set(gca,'OuterPosition',[-0.108733372074584,-0.038575736676064,1.192668199437307,1.042512428103402]);
            end
        end
        
        %% Charge Slice/Session drop down List, initialize plots
        function app = InitSliceDropDown(app)
            % save current results before loading new slice/session
%             SaveResults(app);
            %initial values
            close all
            app.GenesToPlot = {'Gad1', 'Vip', 'Kit','Cnr1','Ndnf', 'Sst','Npy','Pvalb','Tac1','Calb2','Slc6a1','Htr3a','Lhx6','Cxcl14'};
            
            app.currentROI.h1 = {};
            app.currentROI.h2 = {};
            app.currentROI.h3 = [];
            app.currentROI.h4 = [];
            app.currentROI.Pick = 0;
            app.currentROI.Draw = 0;
            app.currentROI.Erase = 0;
            
            app.Replot = 0;
            app.ChangeQual = 0;
            app.Slider_Ch1.Value = 0.2;
            app.Slider_Ch2.Value = 0.2;
            app.Slider_Ch3.Value = 0.2;
            app.SliceID = app.SliceDropDown.Value;
            app.SliceNB = find(strcmp(app.SliceID,app.SliceList));
            
            % load slice
            app = loadiss(app);
            
            % creates initial plot for this session/slice
            app = makePLOT(app);
            app.Replot  = 1;
            app.Init = 1;
            if ~isempty(app.SliceROI)
                hold on
                app.currentROI.h1 = plot(app.Axes1, app.SliceROI(2,:) , app.SliceROI(1,:),'.b','MarkerSize',20);
            end
        end
        
        %% Command the ploting
        function app = makePLOT(app)
            % plot stuffs
            app = plot2(app);
        end
        
        % load ISS results
        function app = loadiss(app)
            %open newSliceID ROISlice object, if it does not
            %exist, creates it
            try 
                load(fullfile(app.folderName,app.SliceID,strcat(app.SliceID,'.mat')))
                try
                     load(fullfile(app.folderName,app.SliceID,strcat(app.SliceID,'_curated.mat')))
                      fprintf('Loading curated pts')
                catch 
                    fprintf('Loading original pts')
                end
                app.SliceROI = pt_list_slice;
            catch
                app.SliceROI = [];
            end
            app.BigImages.GadImg = imadjust(uint16(data_slice.value));
            app.BigImages.DapiImg = 0*app.BigImages.GadImg;
            app.BigImages.GcampImg = 0*app.BigImages.GadImg;
            % ------------ o contains the ISS results, loaded in case ISS
            % is available
            %load(fullfile(app.folderName,strcat('oCall_spots_pixel_',app.SliceID,'_OMP.mat')))
            %app.o = o;
        end
        
        % plot current ROI's boundary in red
        function app = updateCurrentROI(app)
            h3 = app.currentROI.h3;
            if exist('h3')
                delete(h3)
            end
            %plot drawn ROI in red
            Coord_X = app.currentROI.Coord(:,1);
            Coord_Y = app.currentROI.Coord(:,2);
            kk = boundary(Coord_X,Coord_Y,0.1);
            app.currentROI.h3 = plot(app.Axes1, Coord_X(kk) , Coord_Y(kk),'b');
        end
        
        % find ROIs that have been proc
        function app = findProc(app)
            Plane_Idx =  [app.SessionROI.plane(app.SessionROI.Bidx(:,1)>0),app.SessionROI.idx(app.SessionROI.Bidx(:,1)>0)];
            ROI_Plane_Idx = [ app.Reg.ROIplane, app.Reg.ROIidx];
            app.Proc = zeros(size(app.Reg.ROIplane,1),1);
            for iROI = 1: size(app.Reg.ROIplane,1)
                app.Proc(iROI) = ismember(ROI_Plane_Idx(iROI,:),Plane_Idx,'rows');
            end
            app.Proc = logical(app.Proc);
        end
        
        
        function app = EraseROI(app)
            h3 = app.currentROI.h3;
            if exist('h3')
                delete(h3)
            end
        end
        
        function app = DrawThis(app)
            app.currentROI.Coord = app.hROI;
            close(figure(1))
            if isempty(app.SliceROI)
                app.SliceROI{1,1} = app.currentROI.Coord;
            else
                app.SliceROI{end+1,1} = app.currentROI.Coord;
            end
            
            updateCurrentROI(app);
            app.currentROI.Draw = 0;
        end
        
        function SaveResults(app)
            if app.Init == 1
                % save current SliceROI object
                pt_list_slice =  app.SliceROI;
                save(fullfile(app.folderName,app.SliceID,strcat(app.SliceID,'_curated.mat')),'pt_list_slice')
            end
        end
        
        function drawBound(app)
            
            app.currentROI.Coord = app.hROI;
            
            h3 = app.currentROI.h3;
            if exist('h3')
                delete(h3)
            end
            
            close(figure(1))
            if ~ isempty(app.currentROI.Coord)
                if isempty(app.SliceROI)
                    app.SliceROI{1,1} = app.currentROI.Coord;
                else
                    app.SliceROI{end+1,1} = app.currentROI.Coord;
                end
                app.currentROI.idx = size(app.SliceROI,1);
                Coord_X = app.currentROI.Coord(:,1);
                Coord_Y = app.currentROI.Coord(:,2);
                kk = boundary(Coord_X,Coord_Y,0.1);
                app.currentROI.h1{app.currentROI.idx} = plot(app.Axes1, Coord_X(kk) , Coord_Y(kk),'w');
            end
            app.hROI =[];
            app.currentROI.Draw = 0;
        end
        % find ROIs that have been proc
        function app = plotPts(app)
            
            if ~isempty(app.currentROI.h1)
                delete(app.currentROI.h1)
            end
            hold on
            app.currentROI.h1 = plot(app.Axes1, app.SliceROI(2,:) , app.SliceROI(1,:),'.b','MarkerSize',20);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app, SliceList, PathGUI)
            app.SliceList = SliceList;
            app.SliceDropDown.Items = app.SliceList;
            app.folderName = PathGUI;
        end

        % Button pushed function: LoadthisSessionSliceButton
        function LoadthisSessionSliceButtonPushed(app, event)
            close all
            InitSliceDropDown(app)
        end

        % Callback function
        function WarningEditFieldValueChanged(app, event)
            %             value = app.WarningEditField.Value;
            
        end

        % Callback function
        function LoadingEditFieldValueChanged(app, event)
            %             value = app.LoadingEditField.Value;
            
        end

        % Callback function
        function Ch1MinEditFieldValueChanged(app, event)
            plot2(app);
        end

        % Callback function
        function Ch2MinEditFieldValueChanged(app, event)
            plot2(app);
        end

        % Callback function
        function Slider_Ch1ValueChanged(app, event)
            plot2(app);
        end

        % Callback function
        function Slider_Ch2ValueChanged(app, event)
            plot1(app);
        end

        % Value changed function: Ch1MinEditField_2
        function Ch1MinEditField_2ValueChanged(app, event)
            plot2(app);
            
        end

        % Value changed function: Ch2MinEditField_2
        function Ch2MinEditField_2ValueChanged(app, event)
            plot2(app);
            
        end

        % Value changed function: Slider_Ch1
        function Slider_Ch1ValueChanged2(app, event)
            plot2(app);
            
        end

        % Value changed function: Slider_Ch2
        function Slider_Ch2ValueChanged2(app, event)
            plot2(app);
            
        end

        % Value changed function: Ch2MinEditField_3
        function Ch2MinEditField_3ValueChanged(app, event)
            plot2(app);
            
        end

        % Value changed function: Slider_Ch3
        function Slider_Ch3ValueChanged(app, event)
            plot2(app);
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            plot2(app);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            plot2(app);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            SaveResults(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             SaveResults(app);
            delete(app)
        end

        % Value changed function: ShowAllgenesCheckBox
        function ShowAllgenesCheckBoxValueChanged(app, event)
             value = app.ShowAllgenesCheckBox.Value;
            if value == 1
                iss_show_genes({},app.Axes1);
            elseif value == 0
                iss_show_genes(app.GenesToPlot,app.Axes1);
            end
            
            
        end

        % Button pushed function: Rotate90Button
        function Rotate90ButtonPushed(app, event)
             camroll(app.Axes1,90)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SliceDropDownLabel = uilabel(app.UIFigure);
            app.SliceDropDownLabel.Text = 'Slice';
            app.SliceDropDown = uidropdown(app.UIFigure);
            app.ShowAllgenesCheckBox = uicheckbox(app.UIFigure);
            app.ShowAllgenesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAllgenesCheckBoxValueChanged, true);
            app.ShowAllgenesCheckBox.Text = 'Show All genes';
            app.LoadthisSessionSliceButton = uibutton(app.UIFigure, 'push');
            app.LoadthisSessionSliceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthisSessionSliceButtonPushed, true);
            app.LoadthisSessionSliceButton.Text = 'Load this Session/Slice';
            app.Ch1MinEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Ch1MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @Ch1MinEditField_2ValueChanged, true);
            app.Ch2MinEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Ch2MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @Ch2MinEditField_2ValueChanged, true);
            app.MinLabel_2 = uilabel(app.UIFigure);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.UIFigure);
            app.MaxLabel_2.Text = 'Max';
            app.GadLabel = uilabel(app.UIFigure);
            app.GadLabel.Text = 'Gad';
            app.GcampLabel = uilabel(app.UIFigure);
            app.GcampLabel.Text = 'Gcamp';
            app.Slider_Ch1 = uislider(app.UIFigure);
            app.Slider_Ch1.Limits = [0 1];
            app.Slider_Ch1.MajorTicks = [0 0.5 1];
            app.Slider_Ch1.ValueChangedFcn = createCallbackFcn(app, @Slider_Ch1ValueChanged2, true);
            app.Slider_Ch1.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.Slider_Ch1.Value = 0.2;
            app.Slider_Ch2 = uislider(app.UIFigure);
            app.Slider_Ch2.Limits = [0 1];
            app.Slider_Ch2.MajorTicks = [0 0.5 1];
            app.Slider_Ch2.ValueChangedFcn = createCallbackFcn(app, @Slider_Ch2ValueChanged2, true);
            app.Slider_Ch2.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.Slider_Ch2.Value = 1;
            app.Ch2MinEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Ch2MinEditField_3.ValueChangedFcn = createCallbackFcn(app, @Ch2MinEditField_3ValueChanged, true);
            app.DAPILabel = uilabel(app.UIFigure);
            app.DAPILabel.Text = 'DAPI';
            app.Slider_Ch3 = uislider(app.UIFigure);
            app.Slider_Ch3.Limits = [0 1];
            app.Slider_Ch3.MajorTicks = [0 0.5 1];
            app.Slider_Ch3.ValueChangedFcn = createCallbackFcn(app, @Slider_Ch3ValueChanged, true);
            app.Slider_Ch3.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.Slider_Ch3.Value = 0.2;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.MinLabel_3 = uilabel(app.UIFigure);
            app.MinLabel_3.Text = 'Min';
            app.MaxLabel_3 = uilabel(app.UIFigure);
            app.MaxLabel_3.Text = 'Max';
            app.MinLabel_4 = uilabel(app.UIFigure);
            app.MinLabel_4.Text = 'Min';
            app.MaxLabel_4 = uilabel(app.UIFigure);
            app.MaxLabel_4.Text = 'Max';
            app.Rotate90Button = uibutton(app.UIFigure, 'push');
            app.Rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed, true);
            app.Rotate90Button.Text = 'Rotate 90';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_CurateSliceNeuroReg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn2(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end