classdef GUI_StackCuration_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LoadthisanimalButton  matlab.ui.control.Button
        SaveResultsButton     matlab.ui.control.Button
        SliceSliderLabel      matlab.ui.control.Label
        SliceSlider           matlab.ui.control.Slider
        Channel1SliderLabel   matlab.ui.control.Label
        Channel1Slider        matlab.ui.control.Slider
        Channel2SliderLabel   matlab.ui.control.Label
        Channel2Slider        matlab.ui.control.Slider
        Channel1CheckBox      matlab.ui.control.CheckBox
        Channel2CheckBox      matlab.ui.control.CheckBox
        ListBoxLabel          matlab.ui.control.Label
        ListBox               matlab.ui.control.ListBox
        Channel3SliderLabel   matlab.ui.control.Label
        Channel3Slider        matlab.ui.control.Slider
    end

    
    properties (Access = public)
        fileName
        ROI_ID
        Init
        GenesToPlot
        currentROI
        Replot
        SliceROI
        Range
        hROI
        ISSplot
        ChangeQual
        Axes1
        mainFig
        ToolMode
        StackRabies
        Stack
        StackG
        CurrentSlice
        GoodPts
        Integ
    end
    
    methods (Access = public)
        
        %% plot ISS results
        function app = plot2(app)
            ImStack = squeeze(app.Stack(:,round(app.CurrentSlice),:));
            ImStack = ImStack./max(app.Stack(:));
            ImStack = imadjust(ImStack,[0 app.Channel1Slider.Value]);
            if ~isempty(app.StackG)
            Im2 = squeeze(app.StackG(:,round(app.CurrentSlice),:));
            Im2 = Im2./max(app.StackG(:));
            Im2 = imadjust(Im2,[0 app.Channel2Slider.Value]);
            else
               Im2 =  ImStack * 0;
            end
            %             Im3 = squeeze(app.StackRabies(:,round(app.CurrentSlice),:));
            %             Im3 = Im2./max(app.StackRabies(:));
            %             Im3 = imadjust(Im3,[0 app.Channel3Slider.Value]);
            if ~app.Channel1CheckBox.Value
                ImStack = 0*ImStack;
            end
            if ~app.Channel2CheckBox.Value
                Im2 = 0*ImStack;
            end
            ImRed = cat(3,ImStack,Im2, ImStack*0);
            ImRed = uint8(ImRed*255);
%             ImRed = imresize(ImRed,0.75);
            [S1, S2, ~] = size(ImRed);
            if  app.Replot  == 0
                
                app.mainFig = figure(1);
                set(app.mainFig,'units','normalized','outerposition',[0.2 0.1 0.8 0.90])
                app.Axes1 = gca;
                cla(app.Axes1)
                
                app.Axes1.Tag = 'IssIm';
                app.ISSplot = imshow(ImRed);
                addToolbarExplorationButtons(app.mainFig) % Add the axes controls back to the figure toolbar
                app.Axes1.Toolbar.Visible = 'off'; % Hide the integrated axes toolbar
                app.ToolMode = 'Exploration.Pan';
                set(app.mainFig,'WindowKeyPressFcn',@app.KeyPress_StackCuration)
                set(app.mainFig,'windowscrollWheelFcn', @app.scrollPlanes);
%                 set(gca,'Units','pixels')
%                 pos = get(gca,'Position');
%                 set(gca,'Position',[round(pos(1))  round(pos(2))  S1+round(pos(1)) S2+round(pos(1))]);
                
            else
                set(app.ISSplot,'CData',ImRed);
%                 S2 = S2 / 1.8;
                
                switch   app.ListBox.Value
                    case 'TopLeft'
                        xlim([0 S2/2+50 ])
                        ylim([0 S1/2+50])
                    case 'TopRight'
                        xlim([0 S2/2+50])
                        ylim([S1/2-50 S1])
                    case 'BottomRight'
                        xlim([S2/2-50 S2])
                        ylim([S1/2-50 S1])
                    case 'BottomLeft'
                        xlim([S2/2-50 S2])
                        ylim([0 S1/2+50])
                    case 'Full'
                        xlim([0 S2])
                        ylim([0 S1])
                end
                
            end
            %             [~, xpoly, ypoly] = roipoly;
            
            if app.Init == 0
                camroll(app.Axes1,-90)
                set(gca,'XDir','Reverse');
                set(gca,'Position',[0.05,0.05,0.95,0.95]);
                set(gca,'OuterPosition',[-0.108733372074584,-0.038575736676064,1.192668199437307,1.042512428103402]);
%                 set(gcf,'Position',[0.13515625,0.253472222222222,0.74765625,0.669444444444445])
            end
            app = plotPts(app);
        end
        
        %% Charge Slice/Session drop down List, initialize plots
        function app = InitSliceDropDown(app)
            % save current results before loading new slice/session
            SaveResults(app);
            %initial values
            app.currentROI.h1 = {};
            app.currentROI.h2 = {};
            app.currentROI.h3 = [];
            app.currentROI.h4 = [];
            app.currentROI.Pick = 0;
            app.currentROI.Draw = 0;
            app.currentROI.Erase = 0;
            app.CurrentSlice = 10;
            app.SliceSlider.Value = app.CurrentSlice;
            app.Replot = 0;
            app.Integ = 5;
            
            % creates initial plot for this session/slice
            app = plot2(app);
            app.Replot  = 1;
            app.Init = 1;
        end
        
        
        % find ROIs that have been proc
        function app = plotPts(app)
            
            if ~isempty(app.currentROI.h1)
                delete(app.currentROI.h1)
            end
            app = findPts(app);
            hold on
            app.currentROI.h1 = plot(app.Axes1, app.SliceROI(3,app.GoodPts) , app.SliceROI(1,app.GoodPts),'b.','MarkerSize',20);
        end
        
        function SaveResults(app)
            if app.Init == 1
                % save current SliceROI object
                pt_list_vol =  app.SliceROI;
                save(app.fileName,'pt_list_vol')
            end
        end
        
        function app = findPts(app)
            app.GoodPts= app.SliceROI(2,:) >= app.CurrentSlice-app.Integ & app.SliceROI(2,:) <= app.CurrentSlice+app.Integ;
        end
        
        function app = scrollPlanes(app,event,key)
            app.SliceSlider.Value = app.SliceSlider.Value + key.VerticalScrollAmount* key.VerticalScrollCount/3;
              app.CurrentSlice = app.SliceSlider.Value;
            app = plot2(app); 
        end
    end
    
    methods (Access = private)
        
        function app = KeyPress_StackCuration(app,~,keydata)
            key = keydata.Key;
            
            % erase current ROI - Press 'e'
            if strcmp(key,'e')
                %     zoom(app.Axes1, 'off');
                %     pan(app.Axes1, 'off');
                [X, Y] = getpts(app.Axes1);
                Z = app.CurrentSlice*ones(length(X),1);
                NewPts = [Y,Z,X]';
                tt=[];
                for i = 1:size(NewPts,2)
                    [~,tt(i)]= min(sum((app.SliceROI - NewPts(:,i)).^2,1));
                end
                app.SliceROI(:,tt) = [];
                app = plotPts(app);
            end
            
            % Switch to draw mode - Press 'q'
            if  strcmp(key,'q')
                %     zoom(app.Axes1, 'off');
                %     pan(app.Axes1, 'off');
                [X, Y] = getpts(app.Axes1);
                Z = app.CurrentSlice*ones(length(X),1);
                NewPts = [Y,Z,X]';
                app.SliceROI = [app.SliceROI,NewPts];
                app = plotPts(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app, PathGUI, PathGUI_rabies, curatedPt, Shift)
            
            % add toolbox paths
            app.Init = 0;
            try
                load(PathGUI)
                app.Stack = dataZ_mid.value;
                try
                    app.StackG = dataGreen_CaI.value;
                catch
                    app.StackG = [];
                end
                
                app.fileName = [PathGUI(1:end-4),'_curated.mat'];
                if exist(app.fileName)~=2
                    if nargin>3
                         clear pt_list_vol
                          clear SliceROI
                        load(curatedPt)
                        try
                        SliceROI = SliceROI + Shift';
                        catch
                           pt_list_vol = pt_list_vol + Shift';
                        end
                    end
                else
                    clear pt_list_vol
                    load(app.fileName)
                    
                end
                
                try
                    app.SliceROI = pt_list_vol;
                catch
                    app.SliceROI = SliceROI;
                end
                
                app.SliceSlider.Limits = [1,size(app.Stack,2)];
                app.Channel1Slider.Limits = [0,1];
                app.Channel1Slider.Value = 1;
                %                 try
                %                     load(PathGUI_rabies)
                %                     app.StackRabies = dataZ_mid.value;
                %                 catch
                %                    app.StackRabies= 0*app.Stack;
                %                 end
                InitSliceDropDown(app);
                
                
            catch
                LOADButtonPushed(app)
            end
            
            
        end

        % Button pushed function: LoadthisanimalButton
        function LOADButtonPushed(app, event)
            
            [file,path] = uigetfile;
            % or app.folderName = 'Your local path';
            if file == 0
                fprintf('\n No file selected')
            else
                % load zstack
                PathGUI = fullfile(path,file);
                startupFcn2(app, PathGUI)
            end
            
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            SaveResults(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            SaveResults(app);
            delete(app)
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.CurrentSlice = app.SliceSlider.Value;
            app = plot2(app);
            
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
%             app.CurrentSlice = event.Value;
%             app = plot2(app);
            
        end

        % Value changed function: Channel2Slider
        function Channel2SliderValueChanged(app, event)
           app = plot2(app);
            
        end

        % Value changed function: Channel1Slider
        function Channel1SliderValueChanged(app, event)
            app = plot2(app);
            
        end

        % Value changed function: Channel1CheckBox
        function Channel1CheckBoxValueChanged(app, event)
            app = plot2(app);
        end

        % Value changed function: Channel2CheckBox
        function Channel2CheckBoxValueChanged(app, event)
            app = plot2(app);
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app = plot2(app);
        end

        % Value changed function: Channel3Slider
        function Channel3SliderValueChanged(app, event)
            app = plot2(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadthisanimalButton = uibutton(app.UIFigure, 'push');
            app.LoadthisanimalButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LoadthisanimalButton.Text = 'Load this animal';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.Channel1SliderLabel = uilabel(app.UIFigure);
            app.Channel1SliderLabel.Text = 'Channel 1';
            app.Channel1Slider = uislider(app.UIFigure);
            app.Channel1Slider.Limits = [0 1];
            app.Channel1Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Channel1Slider.ValueChangedFcn = createCallbackFcn(app, @Channel1SliderValueChanged, true);
            app.Channel1Slider.Value = 1;
            app.Channel2SliderLabel = uilabel(app.UIFigure);
            app.Channel2SliderLabel.Text = 'Channel 2';
            app.Channel2Slider = uislider(app.UIFigure);
            app.Channel2Slider.Limits = [0 1];
            app.Channel2Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Channel2Slider.ValueChangedFcn = createCallbackFcn(app, @Channel2SliderValueChanged, true);
            app.Channel2Slider.Value = 1;
            app.Channel1CheckBox = uicheckbox(app.UIFigure);
            app.Channel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel1CheckBoxValueChanged, true);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.UIFigure);
            app.Channel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel2CheckBoxValueChanged, true);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'TopLeft', 'TopRight', 'BottomLeft', 'BottomRight', 'Full'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Full';
            app.Channel3SliderLabel = uilabel(app.UIFigure);
            app.Channel3SliderLabel.Text = 'Channel 3';
            app.Channel3Slider = uislider(app.UIFigure);
            app.Channel3Slider.Limits = [0 1];
            app.Channel3Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Channel3Slider.ValueChangedFcn = createCallbackFcn(app, @Channel3SliderValueChanged, true);
            app.Channel3Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_StackCuration_light(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn2(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end